classdef proje2cern < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlantWateringAppCerenKlUIFigure  matlab.ui.Figure
        savedataButton                matlab.ui.control.Button
        ThresholdLabel                matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        LineStyleDropDown             matlab.ui.control.DropDown
        LineStyleDropDownLabel        matlab.ui.control.Label
        LineColorDropDown             matlab.ui.control.DropDown
        LineColorDropDownLabel        matlab.ui.control.Label
        PlantWateringAppLabel         matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        saveaspngButton               matlab.ui.control.Button
        printButton                   matlab.ui.control.Button
        saveasfigButton               matlab.ui.control.Button
        MoisturelevelisfineLamp       matlab.ui.control.Lamp
        MoisturelevelisfineLampLabel  matlab.ui.control.Label
        SoilisdryLamp                 matlab.ui.control.Lamp
        SoilisdryLampLabel            matlab.ui.control.Label
        ExitButton                    matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        thresholdValue;
        a = arduino('COM11','Mega2560');
        voltage;
        redLineHandle;
        LineColor;
        LineStyle;
        redLine;
        voltLine;
        nemdata;
        humData;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.thresholdValue = 25; 
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            y=0;
            %stopFlag = false;
            interv =10000;
            x = 0;
            init_time = 1;
            axesHandle = app.UIAxes;
            app.humData = [];

            while init_time < interv

                app.voltage=readVoltage(app.a,'A1');
                switch app.LineColorDropDown.Value

                    case 'purple'
                        app.LineColor = [0.67 0.37 0.88];
                    case 'green'
                        app.LineColor = [0.6 0.9 0.5];
                    case 'blue'
                        app.LineColor = [0 0.5 1];
                end

                switch app.LineStyleDropDown.Value

                    case 'dashed'
                        app.LineStyle = '--';

                    case 'dashed-dot'
                        app.LineStyle = '-.';

                    case 'straight'
                        app.LineStyle = '-';
                end

                disp(app.voltage);

                nemDegeri = 100-(app.voltage.*20);
                disp(nemDegeri);
                x = [x, nemDegeri];
                xValues = (7.5) .* (1:length(x));
                app.voltLine = plot(axesHandle, x,'LineWidth', 2.5);
                app.voltLine.Color = app.LineColor;
                app.voltLine.LineStyle = app.LineStyle;
                grid(axesHandle, 'on');
                ylim(axesHandle,[0,101]);
                xtickValues = 1:7.5:length(xValues);
                xticks(axesHandle, xtickValues);
                xtickLabels = cell(1, length(xtickValues));

                for i = 1:length(xtickValues)
                    xtickLabels{i} = sprintf('%d', i);
                end
                xticklabels(axesHandle, xtickLabels);
                
                init_time = init_time + 1;
                drawnow;

                app.redLine = getappdata(axesHandle, 'RedLineHandle');
                if isempty(app.redLine) || ~isvalid(app.redLine)
                    hold(axesHandle,"on");
                    app.redLine = plot(axesHandle, [x(1), x(end)], [app.thresholdValue, app.thresholdValue],':r','LineWidth', 2);
                    setappdata(axesHandle, 'RedLineHandle', app.redLine);

                else
                    hold(axesHandle,"on");
                    app.redLine.YData = [app.thresholdValue, app.thresholdValue];
                    app.redLine.XData = [app.redLine.XData(1), app.redLine.XData(1) + length(x) - 1];
   
                end

                app.nemdata = nemDegeri;
                app.humData = [app.humData; app.nemdata];

                if nemDegeri < app.thresholdValue 
                    configurePin(app.a, 'D7', 'DigitalOutput'); 
                    writeDigitalPin(app.a, 'D7', 1);
                 
                elseif nemDegeri > app.thresholdValue
                    
                    writeDigitalPin(app.a, 'D7', 0);
                else
                    
                    writeDigitalPin(app.a, 'D7', 0);
                end

                 switch true
                    case nemDegeri < app.thresholdValue % dry
                        app.MoisturelevelisfineLamp.Color = [0.5,0.5,0.5];
                        app.SoilisdryLamp.Color = [0.93,0.69,0.13];
                    otherwise
                        %no need to water
                        app.SoilisdryLamp.Color = [0.5,0.5,0.5];
                        app.MoisturelevelisfineLamp.Color = [0.93,0.69,0.13];
                 end

            end
                
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            writeDigitalPin(app.a, 'D7', 0);
            delete a
            delete(app)
        end

        % Button pushed function: printButton
        function printButtonPushed(app, event)
            new_figure = figure;
            copyobj(app.UIAxes, new_figure);
            printdlg(new_figure);
            close(new_figure);
        end

        % Button pushed function: saveasfigButton
        function saveasfigButtonPushed(app, event)
            [filename, path] = uiputfile('*.fig', 'Save the graph as .fig');

            if isequal(filename, 0)
                return;
            end

            savefig(app.UIAxes.Parent, fullfile(path, filename));
        end

        % Button pushed function: saveaspngButton
        function saveaspngButtonPushed(app, event)
            [filename, path] = uiputfile('*.png', 'Save the graph as .png');

            if isequal(filename, 0)
                return;
            end

            exportgraphics(app.UIAxes.Parent, fullfile(path, filename));
        end

        % Value changed function: Slider
        function SliderValueChanged2(app, event)
            app.thresholdValue = app.Slider.Value;
        end

        % Button pushed function: savedataButton
        function savedataButtonPushed(app, event)
            prompt = 'Enter the filename:';
            dlgtitle = 'Saving Soil Humidity Data as .xlsx';
            defaultInput = 'soilhumidity.xlsx';
            filename = inputdlg(prompt, dlgtitle, 1, {defaultInput});
            if ~isempty(filename)
                fullFilename = filename{1};
                writematrix(app.humData, fullFilename);
            else
                disp('Error. Data not saved. Please enter a filename.');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PlantWateringAppCerenKlUIFigure = uifigure('Visible', 'off');
            app.PlantWateringAppCerenKlUIFigure.Name = 'Plant Watering App - Ceren Kılıç';
            app.UIAxes = uiaxes(app.PlantWateringAppCerenKlUIFigure);
            title(app.UIAxes, 'Soil Humidity Level Graph')
            xlabel(app.UIAxes, 'Time(seconds)')
            ylabel(app.UIAxes, 'Humidity (%)')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.PlantWateringAppCerenKlUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ExitButton = uibutton(app.PlantWateringAppCerenKlUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SoilisdryLampLabel = uilabel(app.PlantWateringAppCerenKlUIFigure);
            app.SoilisdryLampLabel.Text = 'Soil is dry';
            app.SoilisdryLamp = uilamp(app.PlantWateringAppCerenKlUIFigure);
            app.MoisturelevelisfineLampLabel = uilabel(app.PlantWateringAppCerenKlUIFigure);
            app.MoisturelevelisfineLampLabel.Text = 'Moisture level is fine';
            app.MoisturelevelisfineLamp = uilamp(app.PlantWateringAppCerenKlUIFigure);
            app.saveasfigButton = uibutton(app.PlantWateringAppCerenKlUIFigure, 'push');
            app.saveasfigButton.ButtonPushedFcn = createCallbackFcn(app, @saveasfigButtonPushed, true);
            app.saveasfigButton.Text = 'save as fig';
            app.printButton = uibutton(app.PlantWateringAppCerenKlUIFigure, 'push');
            app.printButton.ButtonPushedFcn = createCallbackFcn(app, @printButtonPushed, true);
            app.printButton.Text = 'print';
            app.saveaspngButton = uibutton(app.PlantWateringAppCerenKlUIFigure, 'push');
            app.saveaspngButton.ButtonPushedFcn = createCallbackFcn(app, @saveaspngButtonPushed, true);
            app.saveaspngButton.Text = 'save as png';
            app.Image = uiimage(app.PlantWateringAppCerenKlUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'plant3.png');
            app.PlantWateringAppLabel = uilabel(app.PlantWateringAppCerenKlUIFigure);
            app.PlantWateringAppLabel.Text = ' Plant Watering App';
            app.LineColorDropDownLabel = uilabel(app.PlantWateringAppCerenKlUIFigure);
            app.LineColorDropDownLabel.Text = 'Line Color';
            app.LineColorDropDown = uidropdown(app.PlantWateringAppCerenKlUIFigure);
            app.LineColorDropDown.Items = {'purple', 'green', 'blue'};
            app.LineColorDropDown.Value = 'purple';
            app.LineStyleDropDownLabel = uilabel(app.PlantWateringAppCerenKlUIFigure);
            app.LineStyleDropDownLabel.Text = 'Line Style';
            app.LineStyleDropDown = uidropdown(app.PlantWateringAppCerenKlUIFigure);
            app.LineStyleDropDown.Items = {'dashed', 'dashed-dot', 'straight'};
            app.LineStyleDropDown.Value = 'straight';
            app.Slider = uislider(app.PlantWateringAppCerenKlUIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged2, true);
            app.Slider.Value = 25;
            app.ThresholdLabel = uilabel(app.PlantWateringAppCerenKlUIFigure);
            app.ThresholdLabel.Text = 'Threshold';
            app.savedataButton = uibutton(app.PlantWateringAppCerenKlUIFigure, 'push');
            app.savedataButton.ButtonPushedFcn = createCallbackFcn(app, @savedataButtonPushed, true);
            app.savedataButton.Text = 'save data';
            % Show the figure after all components are created
            app.PlantWateringAppCerenKlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proje2cern

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlantWateringAppCerenKlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlantWateringAppCerenKlUIFigure)
        end
    end
end